classdef finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        PlotPathin2DButton          matlab.ui.control.Button
        errorimgload                matlab.ui.control.Label
        TraceImageButton            matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        imageaddressEditField       matlab.ui.control.EditField
        imageaddressEditFieldLabel  matlab.ui.control.Label
        tracax                      matlab.ui.control.UIAxes
        imax                        matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        StopRobotButton             matlab.ui.control.Button
        StartRobotDrawingButton     matlab.ui.control.Button
        Replotin2DButton            matlab.ui.control.Button
        ScaleEditField              matlab.ui.control.EditField
        ScaleEditFieldLabel         matlab.ui.control.Label
        ImageOriginYEditField       matlab.ui.control.EditField
        ImageOriginYEditFieldLabel  matlab.ui.control.Label
        ImageOriginXEditField       matlab.ui.control.EditField
        ImageOriginXEditFieldLabel  matlab.ui.control.Label
        thisisnotanexactmanipulabilityareaLabel  matlab.ui.control.Label
        MakesurethefigureisintherectanglethisisnotanexactLabel  matlab.ui.control.Label
        pathax                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        I % Description
        traced % Description
        B2 % Description
        runloop % Description
    end
    
    methods (Access = private)
        
        function results = loadimg(app)
            add = app.imageaddressEditField.Value;
        try
            image = imread(add);
            imshow(image,'Parent',app.imax);
            results = image;
            
        catch
            app.errorimgload.Text = "Error Loading the image. Is the path correct?";
        end
            
        end
        
        function [result,trace] = tracer(app, I)


            [BW,thre] = edge(rgb2gray(I),'Canny',[0.0813 0.1281]); 
            %figure, imshow(BW);
            
            BW2 = bwmorph(BW,'skel',Inf);
            %figure, imshow(BW2);
            
            BW3 = bwmorph(BW2,'spur',3);
            %figure, imshow(BW3);
            
            branchPoints = bwmorph(BW3,'branch',1); 
            branchPoints = imdilate(branchPoints,strel('disk',1));
            BW3 = BW3 & ~branchPoints;
            %figure, imshow(BW3)
            
            BWseg = bwareaopen(BW3,10);
            %figure, imshow(BWseg)
            [B,L] = bwboundaries(BWseg,'noholes');
            %imshow(label2rgb(L, @jet, [.5 .5 .5]))
            boundary = B{18};
            edgeind = find(all(circshift(boundary,1)==circshift(boundary,-1),2),1);
            boundary = circshift(boundary,-edgeind+1);
            boundary = boundary(1:ceil(end/2),:);
            
            for i = 1:length(B)
                boundary = B{i};
                edgeind = find(all(circshift(boundary,1)==circshift(boundary,-1),2),1);
                if ~isempty(edgeind)
                    boundary = circshift(boundary,-edgeind+1);
                    boundary = boundary(1:ceil(end/2),:);
                end
                B{i} = boundary;
            end
            result = BWseg;
            trace = B;


        end
        
        function [] = plotPath(app, origin, delta)
            B2 = app.traced;
            B = app.traced;
            
            cla(app.pathax)
            %delete(allchild(app.pathax))
            xlim(app.pathax, 0.5*[-1 1]);
            ylim(app.pathax, 0.5*[-1 1]);
            zlim(app.pathax, 0.5*[-1 1]);

            %figure;
            hold(app.pathax, 'on')
            for i = 1:length(B)
                b = B{i};
                bx = -b(:,2)*delta+origin(1);
                by = b(:,1)*delta+origin(2);
                bz = zeros(length(bx),1);
                B2{i} = [bx by bz];
                plot(bx,by, 'Parent',app.pathax); 
                
            end
            rectangle('Position',[-0.3 0 0.25 0.2],'Parent',app.pathax);
            hold(app.pathax, 'off')
            app.B2 = B2;
            
        end

        
        function robot = Draw_Arm()

            robot = importrobot('Draw_armV6.urdf','MeshPath',pwd);
            
            % % Fix joint limits for the right gipper
            % robot.Bodies{6}.Joint.PositionLimits = ...
            %     [-robot.Bodies{6}.Joint.PositionLimits(2),...
            %     robot.Bodies{6}.Joint.PositionLimits(1)];
            
            % Add dummy link
            dummy = robotics.RigidBody('dummy_link');
            jnt1 = robotics.Joint('dummy_joint');
            tform = trvec2tform([.05 0 0]); % User defined
            setFixedTransform(jnt1,tform);
            dummy.Joint = jnt1;
            addBody(robot,dummy,'body4');
            
            % % Add end effector
            % body2 = robotics.RigidBody('end_effector');
            % jnt2 = robotics.Joint('end_effector_joint');
            % body2.Joint = jnt2;
            % addBody(robot,body2,'dummy_link');
            
            % Add virtual target position
            dummy = robotics.RigidBody('target_link');
            jnt1 = robotics.Joint('target_joint');
            tform = trvec2tform([0 0 0]); % User defined
            setFixedTransform(jnt1,tform);
            dummy.Joint = jnt1;
            addBody(robot,dummy,'dummy_link');
            
            tip = robotics.RigidBody('tip');
            tipjnt = robotics.Joint('tipjnt','fixed');
            tform = trvec2tform([0 0 -0.09]);
            setFixedTransform(tipjnt,tform);
            tip.Joint = tipjnt;
            addBody(robot,tip,'target_link');
            
            %show(robot,randomConfiguration(robot));
            show(robot)
        end
        
        function UIFigureCloseRequest(app, event)
            app.runloop = false;
   
            delete(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            try
                app.I=loadimg(app);
                error = "";
            catch
                error="Error Loading the image. Is the path correct?";
            end
            app.errorimgload.Text = error;
        end

        % Button pushed function: TraceImageButton
        function TraceImageButtonPushed(app, event)
            [result,app.traced] = tracer(app,app.I);
            try
                imshow(result, 'Parent', app.tracax)
            catch
                e='error';
               
            end
            
        end

        % Button pushed function: PlotPathin2DButton
        function PlotPathin2DButtonPushed(app, event)
            
            origin = [0 0];
            delta = 0.001;
            %figure;
            app.plotPath(origin, delta)
            
        end

        % Button pushed function: Replotin2DButton
        function Replotin2DButtonPushed(app, event)
            delta = str2double(app.ScaleEditField.Value);
            x = str2double(app.ImageOriginXEditField.Value);
            y = str2double(app.ImageOriginYEditField.Value);
            origin = [x y];
            
            app.plotPath(origin, delta)
        end

        % Button pushed function: StartRobotDrawingButton
        function StartRobotDrawingButtonPushed(app, event)
            app.runloop = true;
            B2= app.B2;
            robot = Draw_Arm;
            Q = robot.homeConfiguration;
            robot.show(Q,'preservePlot',false,'Frames','on');
            set(gca,'CameraPosition',[7.6740 10.6196 11.3315],...
                      'CameraTarget',[0.0292 -0.0476 0.0280],...
                      'CameraUpVector',[0 0 1],'CameraViewAngle',1.3394,...
                      'DataAspectRatio',[1 1 1],'Projection','perspective');
            
            robot = Draw_Arm;
            pen = robotics.RigidBody('pen');
            penjnt = robotics.Joint('penjnt','fixed');
            dhparam = [0 0 -0.09 0]; % DH
            setFixedTransform(penjnt,dhparam,'dh');
            pen.Joint = penjnt;
            addBody(robot,pen,'target_link');
            robot.show(Q,'preservePlot',false,'Frames','on','Parent',gca);
            lineobj = findobj('Type','Line');
            set(lineobj(1),'Visible','on');
            
            set(gca,'CameraPosition',[7.6740 10.6196 11.3315],...
                      'CameraTarget',[0.0292 -0.0476 0.0280],...
                      'CameraUpVector',[0 0 1],'CameraViewAngle',1.3394,...
                      'DataAspectRatio',[1 1 1],'Projection','perspective');
            hold on;
            figure(gcf);
            
            for i = 1:length(B2)
                b = B2{i};
                plot3(b(:,1),b(:,2),b(:,3)); hold on;
            end
            figure(gcf);


            for j=length(B2):-1:1
                 temp = B2{j}
                b = temp;    
                
                tf = makehgtform('translate',b(1,:))
                
            
                plot3(b(:,1),b(:,2),b(:,3),'b','LineWidth',3);
                figure(gcf);    
                ik = robotics.InverseKinematics('RigidBodyTree',robot);
                [Q,~] = ik('pen',tf,[1 1 0 1 1 1],Q);
                bJoint = arrayfun(@(x) x.JointPosition,Q)
                robot.show(Q,'preservePlot',false,'Frames','off','Parent',gca,'FastUpdate',1);
                lineobj = findobj('Type','Line');
                set(lineobj(1),'Visible','on');
                figure(gcf);
                
                figure(gcf)
                temp2=0;
                for i=1:size(b,1)
                    if app.runloop == false
                    return
                    end
                  pose = [eye(3) b(i,:)';
                      zeros(1,3) 1;];
                  [Q,~] = ik('pen',pose,[1 1 0 1 1 1],Q);
                  robot.show(Q,'preservePlot',false,'Frames','off','Parent',gca,'FastUpdate',1);
                  lineobj = findobj('Type','Line');
                  set(lineobj(1),'Visible','on');
                  drawnow;
                end
            end


        end

        % Button pushed function: StopRobotButton
        function StopRobotButtonPushed(app, event)
            app.runloop = false;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {233, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.imax = uiaxes(app.LeftPanel);
            title(app.imax, 'Image')
            app.tracax = uiaxes(app.LeftPanel);
            title(app.tracax, 'Title')
            app.imageaddressEditFieldLabel = uilabel(app.LeftPanel);
            app.imageaddressEditFieldLabel.Text = 'image address';
            app.imageaddressEditField = uieditfield(app.LeftPanel, 'text');
            app.LoadImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.TraceImageButton = uibutton(app.LeftPanel, 'push');
            app.TraceImageButton.ButtonPushedFcn = createCallbackFcn(app, @TraceImageButtonPushed, true);
            app.TraceImageButton.Text = 'Trace Image';
            app.errorimgload = uilabel(app.LeftPanel);
            app.errorimgload.Text = '';
            app.PlotPathin2DButton = uibutton(app.LeftPanel, 'push');
            app.PlotPathin2DButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPathin2DButtonPushed, true);
            app.PlotPathin2DButton.Text = 'Plot Path in 2D';
            app.RightPanel = uipanel(app.GridLayout);
            app.pathax = uiaxes(app.RightPanel);
            title(app.pathax, 'Expected Robot Path in 2D')
            xlabel(app.pathax, 'X')
            ylabel(app.pathax, 'Y')
            zlabel(app.pathax, 'Z')
            app.MakesurethefigureisintherectanglethisisnotanexactLabel = uilabel(app.RightPanel);
            app.MakesurethefigureisintherectanglethisisnotanexactLabel.Text = 'Make sure the figure is in the rectangle ';
            app.thisisnotanexactmanipulabilityareaLabel = uilabel(app.RightPanel);
            app.thisisnotanexactmanipulabilityareaLabel.Text = '(this is not an exact manipulability area)';
            app.ImageOriginXEditFieldLabel = uilabel(app.RightPanel);
            app.ImageOriginXEditFieldLabel.Text = 'Image Origin (X)';
            app.ImageOriginXEditField = uieditfield(app.RightPanel, 'text');
            app.ImageOriginXEditField.Value = '0';
            app.ImageOriginYEditFieldLabel = uilabel(app.RightPanel);
            app.ImageOriginYEditFieldLabel.Text = 'Image Origin (Y)';
            app.ImageOriginYEditField = uieditfield(app.RightPanel, 'text');
            app.ImageOriginYEditField.Value = '0';
            app.ScaleEditFieldLabel = uilabel(app.RightPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.RightPanel, 'text');
            app.ScaleEditField.Value = '0.001';
            app.Replotin2DButton = uibutton(app.RightPanel, 'push');
            app.Replotin2DButton.ButtonPushedFcn = createCallbackFcn(app, @Replotin2DButtonPushed, true);
            app.Replotin2DButton.Text = 'Re-plot in 2D';
            app.StartRobotDrawingButton = uibutton(app.RightPanel, 'push');
            app.StartRobotDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRobotDrawingButtonPushed, true);
            app.StartRobotDrawingButton.Text = 'Start Robot Drawing';
            app.StopRobotButton = uibutton(app.RightPanel, 'push');
            app.StopRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StopRobotButtonPushed, true);
            app.StopRobotButton.Text = 'Stop Robot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end