classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        StopexecutionButton         matlab.ui.control.Button
        errorimgload                matlab.ui.control.Label
        LoadImageButton             matlab.ui.control.Button
        DrawitButton                matlab.ui.control.Button
        TraceImageButton            matlab.ui.control.Button
        imageaddressEditField       matlab.ui.control.EditField
        imageaddressEditFieldLabel  matlab.ui.control.Label
        tracax                      matlab.ui.control.UIAxes
        imax                        matlab.ui.control.UIAxes
        CenterPanel                 matlab.ui.container.Panel
        drawax                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        I % Description
        traced % Description
        runloop = true
    end
    
    methods (Access = private)
        
        function results = loadimg(app)
            add = app.imageaddressEditField.Value
        try
            image = imread(add);
            imshow(image,'Parent',app.imax);
            results = image;
            
        catch
            app.errorimgload.Text = "Error Loading the image. Is the path correct?"
        end
       
            
        end
        
        function [result,trace] = tracer(app, I)


            [BW,thre] = edge(rgb2gray(I),'Canny',[0.0813 0.1281]); 
            %figure, imshow(BW);
            
            BW2 = bwmorph(BW,'skel',Inf);
            %figure, imshow(BW2);
            
            BW3 = bwmorph(BW2,'spur',3);
            %figure, imshow(BW3);
            
            branchPoints = bwmorph(BW3,'branch',1); 
            branchPoints = imdilate(branchPoints,strel('disk',1));
            BW3 = BW3 & ~branchPoints;
            %figure, imshow(BW3)
            
            BWseg = bwareaopen(BW3,10);
            %figure, imshow(BWseg)
            [B,L] = bwboundaries(BWseg,'noholes');
            %imshow(label2rgb(L, @jet, [.5 .5 .5]))
            boundary = B{18};
            edgeind = find(all(circshift(boundary,1)==circshift(boundary,-1),2),1)
            boundary = circshift(boundary,-edgeind+1);
            boundary = boundary(1:ceil(end/2),:)
            
            for i = 1:length(B)
                boundary = B{i};
                edgeind = find(all(circshift(boundary,1)==circshift(boundary,-1),2),1);
                if ~isempty(edgeind)
                    boundary = circshift(boundary,-edgeind+1);
                    boundary = boundary(1:ceil(end/2),:);
                end
                B{i} = boundary;
            end
            B2 = B;
            origin = [0.48 -0.229/2];
            delta = 0.001;
            %figure;
            for i = 1:length(B)
                b = B{i};
                bx = -b(:,2)*delta+origin(1);
                by = b(:,1)*delta+origin(2);
                bz = zeros(length(bx),1);
                B2{i} = [bx by bz];
                %plot3(bx,by,bz); hold on;
            end
            result = BWseg;
            trace = B2;

        end
    end
    
    methods (Access = public)
        
        function robot = mikata_arm_4(app)
            if ~exist('mikata_arm_4.urdf','file')
                disp('Downloading the URDF and STL files for Mikata Arm...');
                websave('mikata_arm_4.urdf','https://raw.githubusercontent.com/ROBOTIS-JAPAN-GIT/dynamixel_mikata_arm/master/mikata_arm_description/urdf/mikata_arm_4.urdf');
                websave('mikata_arm_4_gripper.stl','https://github.com/ROBOTIS-JAPAN-GIT/dynamixel_mikata_arm/raw/master/mikata_arm_description/meshes/mikata_arm_4_gripper.stl');
                websave('mikata_arm_4_joint1.stl','https://github.com/ROBOTIS-JAPAN-GIT/dynamixel_mikata_arm/raw/master/mikata_arm_description/meshes/mikata_arm_4_joint1.stl');
                websave('mikata_arm_4_joint2.stl','https://github.com/ROBOTIS-JAPAN-GIT/dynamixel_mikata_arm/raw/master/mikata_arm_description/meshes/mikata_arm_4_joint2.stl');
                websave('mikata_arm_4_joint3.stl','https://github.com/ROBOTIS-JAPAN-GIT/dynamixel_mikata_arm/raw/master/mikata_arm_description/meshes/mikata_arm_4_joint3.stl');
                websave('mikata_arm_4_joint4.stl','https://github.com/ROBOTIS-JAPAN-GIT/dynamixel_mikata_arm/raw/master/mikata_arm_description/meshes/mikata_arm_4_joint4.stl');
                websave('mikata_arm_4_joint5.stl','https://github.com/ROBOTIS-JAPAN-GIT/dynamixel_mikata_arm/raw/master/mikata_arm_description/meshes/mikata_arm_4_joint5.stl');
                disp('Done.');
            end
            robot = importrobot('mikata_arm_4.urdf','MeshPath',pwd);
            % Fix joint limits for the right gipper
            robot.Bodies{6}.Joint.PositionLimits = ...
                [-robot.Bodies{6}.Joint.PositionLimits(2),...
                robot.Bodies{6}.Joint.PositionLimits(1)];
            % Add dummy link
            body1 = robotics.RigidBody('dummy_link');
            jnt1 = robotics.Joint('dummy_joint');
            tform = trvec2tform([.04225 0 0]); % User defined
            setFixedTransform(jnt1,tform);
            body1.Joint = jnt1;
            addBody(robot,body1,'link_5');
            % Add end effector
            body2 = robotics.RigidBody('end_effector');
            jnt2 = robotics.Joint('end_effector_joint');
            body2.Joint = jnt2;
            addBody(robot,body2,'dummy_link');
            % Add virtual target position
            body1 = robotics.RigidBody('target_link');
            jnt1 = robotics.Joint('target_joint');
            tform = trvec2tform([.093 0 0]); % User defined
            setFixedTransform(jnt1,tform);
            body1.Joint = jnt1;
            addBody(robot,body1,'dummy_link');


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            
            app.I=loadimg(app);

        end

        % Button pushed function: TraceImageButton
        function TraceImageButtonPushed(app, event)
            
            [result,app.traced] = tracer(app,app.I);
            try
                imshow(result, 'Parent', app.tracax)
            catch
                a='error';
            end


        end

        % Button pushed function: DrawitButton
        function DrawitButtonPushed(app, event)
            app.runloop = true;
            B2 = app.traced;
            figure;
            mikata_arm_4(app);
            robot = importrobot('mikata_arm_4.urdf','MeshPath','.');
            Q = robot.homeConfiguration;
            robot.show(Q,'preservePlot',false,'Frames','off');
            set(gca,'CameraPosition',[7.6740 10.6196 11.3315],...
                      'CameraTarget',[0.0292 -0.0476 0.0280],...
                      'CameraUpVector',[0 0 1],'CameraViewAngle',1.3394,...
                      'DataAspectRatio',[1 1 1],'Projection','perspective');
            
            robot = mikata_arm_4(app);
            pen = robotics.RigidBody('pen');
            penjnt = robotics.Joint('penjnt','fixed');
            dhparam = [0 0 -0.09 0]; % DHパラメータ
            setFixedTransform(penjnt,dhparam,'dh');
            pen.Joint = penjnt;
            addBody(robot,pen,'target_link');
            robot.show(Q,'preservePlot',false,'Frames','off','Parent',gca);
            lineobj = findobj('Type','Line');
            set(lineobj(1),'Visible','on');
            
            set(gca,'CameraPosition',[7.6740 10.6196 11.3315],...
                      'CameraTarget',[0.0292 -0.0476 0.0280],...
                      'CameraUpVector',[0 0 1],'CameraViewAngle',1.3394,...
                      'DataAspectRatio',[1 1 1],'Projection','perspective');
            hold on;
            figure(gcf);
            
            for i = 1:length(B2)
                b = B2{i};
                plot3(b(:,1),b(:,2),b(:,3)); hold on;
            end
            figure(gcf);


            for j=length(B2):-1:1
                
                 temp = B2{j}
                b = temp;    
                
                tf = makehgtform('translate',b(1,:))
                
            
                plot3(b(:,1),b(:,2),b(:,3),'b','LineWidth',3);
                figure(gcf);    
                ik = robotics.InverseKinematics('RigidBodyTree',robot);
                [Q,~] = ik('pen',tf,[1 1 0 1 1 1],Q);
                bJoint = arrayfun(@(x) x.JointPosition,Q)
                robot.show(Q,'preservePlot',false,'Frames','off','Parent',gca);
                lineobj = findobj('Type','Line');
                set(lineobj(1),'Visible','on');
                figure(gcf);
                
                figure(gcf)
                temp2=0;
                for i=1:size(b,1)
                    if app.runloop == false
                    return
                    end
                  pose = [eye(3) b(i,:)';
                      zeros(1,3) 1;];
                  [Q,~] = ik('pen',pose,[1 1 0 1 1 1],Q);
                  robot.show(Q,'preservePlot',false,'Frames','off','Parent',gca);
                  lineobj = findobj('Type','Line');
                  set(lineobj(1),'Visible','on');
                  drawnow;
                end
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.runloop = false;
   
            delete(app)
            
        end

        % Button pushed function: StopexecutionButton
        function StopexecutionButtonPushed(app, event)
            app.runloop = false;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.imax = uiaxes(app.LeftPanel);
            title(app.imax, 'Image')
            app.tracax = uiaxes(app.LeftPanel);
            title(app.tracax, 'Title')
            xlabel(app.tracax, 'X')
            ylabel(app.tracax, 'Y')
            zlabel(app.tracax, 'Z')
            app.imageaddressEditFieldLabel = uilabel(app.LeftPanel);
            app.imageaddressEditFieldLabel.Text = 'image address';
            app.imageaddressEditField = uieditfield(app.LeftPanel, 'text');
            app.TraceImageButton = uibutton(app.LeftPanel, 'push');
            app.TraceImageButton.ButtonPushedFcn = createCallbackFcn(app, @TraceImageButtonPushed, true);
            app.TraceImageButton.Text = 'Trace Image';
            app.DrawitButton = uibutton(app.LeftPanel, 'push');
            app.DrawitButton.ButtonPushedFcn = createCallbackFcn(app, @DrawitButtonPushed, true);
            app.DrawitButton.Text = 'Draw it!';
            app.LoadImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.errorimgload = uilabel(app.LeftPanel);
            app.errorimgload.Text = '';
            app.StopexecutionButton = uibutton(app.LeftPanel, 'push');
            app.StopexecutionButton.ButtonPushedFcn = createCallbackFcn(app, @StopexecutionButtonPushed, true);
            app.StopexecutionButton.Text = 'Stop execution';
            app.CenterPanel = uipanel(app.GridLayout);
            app.drawax = uiaxes(app.CenterPanel);
            title(app.drawax, 'Title')
            xlabel(app.drawax, 'X')
            ylabel(app.drawax, 'Y')
            zlabel(app.drawax, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end